classdef Results_Module < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        BaseMenu                        matlab.ui.container.Menu
        SavetoBaseandExitMenu           matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        TabGroup                        matlab.ui.container.TabGroup
        RawResultsTab                   matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        EndDataReductionButton          matlab.ui.control.Button
        ShowDropDown                    matlab.ui.control.DropDown
        ShowDropDownLabel               matlab.ui.control.Label
        BracketIdDropDown               matlab.ui.control.DropDown
        BracketIdDropDownLabel          matlab.ui.control.Label
        PrimaryReferenceMaterialDropDown  matlab.ui.control.DropDown
        PrimaryReferenceMaterialDropDownLabel  matlab.ui.control.Label
        CreateOutputTableButton         matlab.ui.control.Button
        HostInclusionDropDown           matlab.ui.control.DropDown
        ResultsTable                    matlab.ui.control.Table
        AddMetadataTab                  matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        CustomEditField                 matlab.ui.control.EditField
        CustomEditFieldLabel            matlab.ui.control.Label
        CommentCheckBox                 matlab.ui.control.CheckBox
        LocationCheckBox                matlab.ui.control.CheckBox
        MineralCheckBox                 matlab.ui.control.CheckBox
        SampleCheckBox                  matlab.ui.control.CheckBox
        RemoveButton                    matlab.ui.control.Button
        AddButton                       matlab.ui.control.Button
        Panel                           matlab.ui.container.Panel
        EditField                       matlab.ui.control.EditField
        StringCompareDropDown           matlab.ui.control.DropDown
        SpotnamecontainsEditField       matlab.ui.control.EditField
        SpotnamecontainsEditFieldLabel  matlab.ui.control.Label
        ApplyButton                     matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        QualityControlTab               matlab.ui.container.Tab
        CreateOutputTable               matlab.ui.container.Tab
        SignalandBackgroundLengthLabel  matlab.ui.control.Label
        ShowDropDown_4                  matlab.ui.control.DropDown
        Averageand2sdpersamplemineraluswLabel  matlab.ui.control.Label
        ColumnheaderswithoutMassesmassesextrarowLabel  matlab.ui.control.Label
        SignificantdigitsLabel          matlab.ui.control.Label
        WhichcolumnstoincludedefinedinmetadataandyieldLabel  matlab.ui.control.Label
        ShowDropDown_3                  matlab.ui.control.DropDown
        ShowDropDown_2                  matlab.ui.control.DropDown
        ProgressPanel                   matlab.ui.container.Panel
        ResultsButton                   matlab.ui.control.Button
        QuantificationButton            matlab.ui.control.Button
        DriftButton                     matlab.ui.control.Button
        ClassificationButton            matlab.ui.control.Button
        ImportButton                    matlab.ui.control.Button
        IntervalButton                  matlab.ui.control.Button
    end

    properties (Access = public)
        base
        project_id
        session_id
    end

    properties (Access = private)
        sourceapp
        selected_PRM
        selected_bracket_id
        selected_host_inclusion

        editstatus = false
    end
       

    methods (Access = public)


        function update_progress_buttons(app)

            status = app.base.project.session.meta.module_status;

            green = [204/255, 1, 204/255];
            orange = [1, 229/255, 204/255];
            red = [1, 204/255, 204/255];

            if isfield(status, 'import') && status.import == true
                app.ImportButton.Enable = "on";
                app.ImportButton.BackgroundColor = green;
            else
                app.ImportButton.Enable = "off";
                app.ImportButton.BackgroundColor = red;
            end

            if isfield(status, 'interval') && status.interval == true
                app.IntervalButton.Enable = "on";
                app.IntervalButton.BackgroundColor = green;
            else
                app.IntervalButton.Enable = "off";
                app.IntervalButton.BackgroundColor = red;
            end

            if isfield(status, 'classification') && status.classification == true
                app.ClassificationButton.Enable = "on";
                app.ClassificationButton.BackgroundColor = green;
            else
                app.ClassificationButton.Enable = "off";
                app.ClassificationButton.BackgroundColor = red;
            end

            if isfield(status, 'drift') && status.drift == true
                app.DriftButton.Enable = "on";
                app.DriftButton.BackgroundColor = green;
            else
                app.DriftButton.Enable = "off";
                app.DriftButton.BackgroundColor = red;
            end

            if isfield(status, 'quantification') && status.quantification == true
                app.QuantificationButton.Enable = "on";
                app.QuantificationButton.BackgroundColor = green;
            else
                app.QuantificationButton.Enable = "off";
                app.QuantificationButton.BackgroundColor = red;
            end

            app.ResultsButton.Enable = "on";
            app.ResultsButton.BackgroundColor = orange;
            app.ResultsButton.FontWeight = 'bold';

        end


        %%%%%%%%%%%%%%%%%%%%%%%%%% GUI %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function update_results_module_GUI(app)

            % % Get unique reference materials
            % uniquePRMs = unique(app.base.project.session.spot.referencematerial);
            % validPRMs = uniquePRMs(strcmp(uniquePRMs, 'PRM'));
            % 
            % % Populate PRM dropdown
            % app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            % app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

                        % Get indices where type is "PRM"
            prm_indices = strcmp(app.base.project.session.spot.type, "PRM");
            % Get reference materials only for PRM spots
            prm_materials = app.base.project.session.spot.referencematerial(prm_indices);
            % Get unique names, excluding "None" and "Select Reference Material"
            unique_names = unique(prm_materials);
            unique_names = unique_names(~strcmp(unique_names, 'None'));
            validPRMs = unique_names(~strcmp(unique_names, 'Select Reference Material'));

            % Populate PRM dropdown
            app.PrimaryReferenceMaterialDropDown.Items = validPRMs;
            app.PrimaryReferenceMaterialDropDown.ItemsData = 1:numel(app.PrimaryReferenceMaterialDropDown.Items);

            % Get selected reference material and find matching indices
            selectedRM = validPRMs(app.PrimaryReferenceMaterialDropDown.Value);
            rmIndices = strcmp(app.base.project.session.spot.referencematerial, selectedRM);

            % Filter bracket IDs for selected reference material
            filteredBracketIds = app.base.project.session.spot.bracket_id(rmIndices);
            uniqueBracketIds = unique(filteredBracketIds);
            uniqueBracketIds = uniqueBracketIds(uniqueBracketIds ~= 0);

            % Update bracket ID dropdown
            app.BracketIdDropDown.Items = string(uniqueBracketIds);
            app.BracketIdDropDown.ItemsData = uniqueBracketIds;

            % at startup the selected_PRM is empty, make it 1 so that
            % something is plotted anyways
            if isempty(app.selected_PRM)
                app.selected_PRM = 1;
            end
            % Set default bracket_id if empty - use first available bracket_id
            if isempty(app.selected_bracket_id)
                app.selected_bracket_id = uniqueBracketIds(1);
                app.BracketIdDropDown.Value = uniqueBracketIds(1);
            end


            if isequal(app.ShowDropDown.Value,'CPS')
                app.HostInclusionDropDown.Visible = 'off';
                app.HostInclusionDropDown.Enable = 'off';
                app.BracketIdDropDown.Visible = 'off';
                app.BracketIdDropDown.Enable = 'off';
                app.BracketIdDropDownLabel.Visible = 'off';
                app.PrimaryReferenceMaterialDropDown.Visible = 'off';
                app.PrimaryReferenceMaterialDropDown.Enable = 'off';
                app.PrimaryReferenceMaterialDropDownLabel.Visible = 'off';
            else
                if isequal(app.base.project.session.meta.reductiontype, 'Inclusion')
                    app.HostInclusionDropDown.Items = ["Host", "Inclusion"];
                    app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
                elseif isequal(app.base.project.session.meta.reductiontype, 'Matrix')
                    app.HostInclusionDropDown.Visible = 'off';
                    app.HostInclusionDropDown.Enable = 'off';
                    app.HostInclusionDropDown.Items = "Host";
                    app.HostInclusionDropDown.ItemsData = app.HostInclusionDropDown.Items;
                end

                if isempty(app.selected_host_inclusion)
                    app.selected_host_inclusion = app.HostInclusionDropDown.ItemsData{1};
                end
                
                app.BracketIdDropDown.Visible = 'on';
                app.BracketIdDropDown.Enable = 'on';
                app.BracketIdDropDownLabel.Visible = 'on';
                app.PrimaryReferenceMaterialDropDown.Visible = 'on';
                app.PrimaryReferenceMaterialDropDown.Enable = 'on';
                app.PrimaryReferenceMaterialDropDownLabel.Visible = 'on';

            end


            %%%%%%%%%%%%%%%%%%%%
            update_results_table(app)
            update_progress_buttons(app)


        end


        function update_results_table(app)

            % preallocate the columns
            spotnames = app.base.project.session.spotname(:);  % ensure column vector
            spotNumbers = (1:numel(spotnames))';  % create column vector of numbers

            % Generate initial table with spot numbers and names
            T = table(spotNumbers, spotnames, 'VariableNames', {'#', 'Spotname'});

            if isequal(app.ShowDropDown.Value,'CPS')

                if isequal(app.selected_host_inclusion,'Host')

                    cps = app.base.project.session.spot.bkgd_corrected_cps;

                elseif isequal(app.selected_host_inclusion,'Inclusion')

                    cps = app.base.project.session.spot.bkgd_corrected_cps_incl;

                end

                T = [T, cps];

            elseif isequal(app.ShowDropDown.Value,'Mass Fractions')

                if isequal(app.selected_host_inclusion,'Host')
                    compositions = app.base.project.session.spot.matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    compositions = app.base.project.session.spot.inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM};
                    limits_of_detection = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                end

                % Convert tables to arrays for manipulation
                comp_array = table2array(compositions);
                lod_array = table2array(limits_of_detection);

                % Create logical mask for values below detection limit
                below_lod = comp_array < lod_array;

                % Create cell array for display values
                display_values = num2cell(comp_array);

                % Format detection limit values where needed
                display_values(below_lod) = arrayfun(@(x) ['<' num2str(x)], lod_array(below_lod), 'UniformOutput', false);

                % Convert back to table with original variable names
                display_compositions = array2table(display_values, 'VariableNames', compositions.Properties.VariableNames);

                % Combine with modified composition data
                T = [T, display_compositions];

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(comp_array ~= 0, 2);

                % Filter table to keep only non-zero rows
                T = T(non_zero_mask, :);

            elseif isequal(app.ShowDropDown.Value,'Sensitivities')

                if isequal(app.selected_host_inclusion,'Host')
                    sensitivities = app.base.project.session.spot.sensitivities{app.BracketIdDropDown.Value, app.selected_PRM};
                end

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(sensitivities) ~= 0, 2);

                % Filter table to keep only non-zero rows
                sensitivities = sensitivities(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, sensitivities];

            elseif isequal(app.ShowDropDown.Value,'Limits of detection')

                if isequal(app.selected_host_inclusion,'Host')
                    lods = app.base.project.session.spot.matrix_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                elseif isequal(app.selected_host_inclusion,'Inclusion')
                    lods = app.base.project.session.spot.inclusion_limits_of_detection{app.BracketIdDropDown.Value, app.selected_PRM};
                end

                % Create mask for non-zero rows (check only composition columns)
                non_zero_mask = any(table2array(lods) ~= 0, 2);

                % Filter table to keep only non-zero rows
                lods = lods(non_zero_mask, :);

                T = T(non_zero_mask, :);

                % Combine with modified composition data
                T = [T, lods];


            end

            % Update the displayed table
            app.ResultsTable.Data = T;
            app.ResultsTable.ColumnName = T.Properties.VariableNames;

        end


        % Preliminary Table Outputs
        % function preliminary_matrix_output_table(app, outputpath)
        % 
        %     if isfield(app.base.project.session.spot, 'matrix_composition')
        %         % Get the matrix composition data and spotnames
        %         matrix_composition = app.base.project.session.spot.matrix_composition;
        %         spotnames = app.base.project.session.spotname;
        % 
        %         uniquePRMs = unique(app.base.project.session.spot.referencematerial);
        %         validPRMs = uniquePRMs(~strcmp(uniquePRMs, 'None'));
        % 
        %         % get the indices of all rows that are not all 0
        %         unk_rows_idx = find(any(table2array(matrix_composition{app.BracketIdDropDown.Value, app.selected_PRM}), 2));
        % 
        %         % Iterate through each bracket (rows) and PRM (columns)
        %         for bracket = 1:size(matrix_composition, 1)
        %             for prm = 1:size(matrix_composition, 2)
        %                 % Get current table
        %                 current_table = matrix_composition{bracket, prm};
        % 
        %                 % Filter table to only include rows from unk_rows_idx
        %                 current_table = current_table(unk_rows_idx,:);
        % 
        %                 % Create new table with Nr. and Spotnames columns
        %                 Nr = unk_rows_idx(:);  % spot numbers
        %                 Spotnames = spotnames(unk_rows_idx);
        % 
        %                 % Combine all columns
        %                 inclusion_table = addvars(current_table, Nr, Spotnames, 'Before', 1);
        % 
        %                 % Create sheet name combining PRM name and bracket ID
        %                 sheet_name = sprintf('%s bracket_%d', char(validPRMs(prm)), bracket);
        % 
        %                 % Write filtered table to its own sheet in the Excel file
        %                 writetable(inclusion_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        %             end
        %         end
        %     end
        % end
        % 
        % 
        % function preliminary_inclusion_output_table(app, outputpath)
        % 
        %     if isequal(app.base.project.session.meta.reductiontype, 'Inclusion') && isfield(app.base.project.session.spot, 'inclusion_composition')
        %         % Get the matrix composition data and spotnames
        %         inclusion_composition = app.base.project.session.spot.inclusion_composition;
        %         spotnames = app.base.project.session.spotname;
        % 
        %         uniquePRMs = unique(app.base.project.session.spot.referencematerial);
        %         validPRMs = uniquePRMs(~strcmp(uniquePRMs, 'None'));
        % 
        %         % get the indices of all rows that are not all 0
        %         unk_rows_idx = find(any(table2array(inclusion_composition{app.BracketIdDropDown.Value, app.selected_PRM}), 2));
        % 
        %         % Iterate through each bracket (rows) and PRM (columns)
        %         for bracket = 1:size(inclusion_composition, 1)
        %             for prm = 1:size(inclusion_composition, 2)
        %                 % Get current table
        %                 current_table = inclusion_composition{bracket, prm};
        % 
        %                 % Filter table to only include rows from unk_rows_idx
        %                 current_table = current_table(unk_rows_idx,:);
        % 
        %                 % Create new table with Nr. and Spotnames columns
        %                 Nr = unk_rows_idx(:);  % spot numbers
        %                 Spotnames = spotnames(unk_rows_idx);
        % 
        %                 % Combine all columns
        %                 inclusion_table = addvars(current_table, Nr, Spotnames, 'Before', 1);
        % 
        %                 % Create sheet name combining PRM name and bracket ID
        %                 sheet_name = sprintf('%s bracket_%d', char(validPRMs(prm)), bracket);
        % 
        %                 % Write filtered table to its own sheet in the Excel file
        %                 writetable(inclusion_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        %             end
        %         end
        % 
        %     end
        % 
        % 
        % end
        % 

        function export_displayed_table(app, outputpath)
            % Get the currently displayed table
            displayed_table = app.ResultsTable.Data;

            % Get column names
            column_names = app.ResultsTable.ColumnName;

            % Convert to table if it's not already
            if ~istable(displayed_table)
                displayed_table = array2table(displayed_table, 'VariableNames', column_names);
            end

            % Create sheet name based on current selection
            sheet_name = sprintf(char(unique(app.base.project.session.spot.referencematerial(app.selected_PRM))), ...
                app.BracketIdDropDown.Value);

            % Write the table to Excel
            writetable(displayed_table, outputpath, 'Sheet', sheet_name, 'WriteRowNames', false);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, sourceapp, project_name, project_id, session_id, data)
            
            if exist("sourceapp","var")
                app.sourceapp = sourceapp;
            end% input argument of startupfcn

            if exist("project_name", "var")
                app.base.project_name = project_name;
            end

            %% when the show session button is pressed & when the Interval_Module is called from another Module
            if  exist("data", "var") % e.g. for API call

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.meta.module_status.results = true;


            elseif exist("project_id","var") && exist("session_id","var") % coming from the DIQES base

                app.base.project.session =  app.sourceapp.base(project_id).project(session_id).session;
                app.base.project.session.meta.module_status.results = true;

            end

            app.project_id = project_id;
            app.session_id = session_id;
            app.UIFigure.Name = [char(app.base.project_name) ' - ' char(app.base.project.session.session_name)]; %string(app.base.project.session.session_name);

            update_results_module_GUI(app)
        end

        % Menu selected function: SavetoBaseandExitMenu
        function SavetoBaseandExitMenuSelected(app, event)
            
            try

                app.base.project.session.meta.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      
            
        end

        % Value changed function: PrimaryReferenceMaterialDropDown
        function PrimaryReferenceMaterialDropDownValueChanged(app, event)
            app.selected_PRM = app.PrimaryReferenceMaterialDropDown.Value;

            update_results_module_GUI(app)
        end

        % Value changed function: BracketIdDropDown
        function BracketIdDropDownValueChanged(app, event)
            app.selected_bracket_id = app.BracketIdDropDown.Value;

            update_results_module_GUI(app)
        end

        % Button pushed function: EndDataReductionButton
        function EndDataReductionButtonPushed(app, event)
                    
            try

                app.base.project.session.meta.module_status.results = true;

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);
                        delete(app)
                    case "Don't save"
                        delete(app)
                    case 'Cancel'
                        return
                end

            catch ME
                return
            end      


        end

        % Value changed function: HostInclusionDropDown
        function HostInclusionDropDownValueChanged(app, event)
            app.selected_host_inclusion = app.HostInclusionDropDown.Value;
            
            update_results_module_GUI(app)
        end

        % Button pushed function: ClassificationButton, DriftButton, 
        % ...and 3 other components
        function QuantificationButtonPushed(app, event)
             try

                if app.editstatus == true
                    % uiconfirm ask user if finished
                    fig = app.UIFigure;
                    msg = "Do you want to proceed and save the data?";
                    title = "ICP-Base";
                    selection = uiconfirm(fig,msg,title, ...
                        "Options",["Save","Don't save","Cancel"], ...
                        "DefaultOption",1,"CancelOption",3);
                else
                    selection = 'Save';
                end

                switch selection
                    case 'Save'
                        
                        [app.project_id, app.session_id] = save_to_base(app.sourceapp, app.project_id, app.base.project.session, app.session_id, app.base.project_name, settings);

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case "Don't save"

                        switch event.Source.Parent.Parent.Parent.Parent.CallbackObject.Text
                            case 'Import'
                                Import_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Interval'
                                Interval_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Classification'
                                Classification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Drift'
                                Drift_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Quantification'
                                Quantification_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                            case 'Results'
                                Results_Module(app.sourceapp, app.base.project_name, app.project_id, app.session_id)
                        end

                        delete(app)

                    case 'Cancel'

                        return
                end

            catch ME
                DisplayError(ME,'Proceed to other module failed')
                message = "Proceeding to other module failed";
                uialert(app.UIFigure,message,"Warning", ...
                    "Icon","warning");

                return
            end
        end

        % Button pushed function: CreateOutputTableButton
        function CreateOutputTableButtonPushed(app, event)
             [filename, pathname] = uiputfile('*.xlsx', 'Save Excel file');
            outputpath = fullfile(pathname, filename);
            if isequal(filename, 0) || isequal(pathname, 0)
                return; % User canceled file selection
            end

            %outputpath = 'C:\Users\Sebastian\OneDrive - Universitaet Bern\PhD\ICP-Base\Test Data\Output Test\Gana_Rossa.xlsx';
            %app.base.project.session.outputpath = fullfile(pathname, filename);
            export_displayed_table(app, outputpath)


        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
             changes = check_changes(app);
            if changes == false
                return
            end
            app.editstatus = true;


           selected_string = app.SpotnamecontainsEditField.Value;

            % Initialize an empty array to store the indices of the selected spots
            selected_spot_indices = [];

            % Iterate through the spot names
            for i = 1:length(app.base.project.session.spotname)
                % Check if the spot name contains the selected string
                if contains(app.base.project.session.spotname{i}, selected_string) && ismember(i, unique(app.all_white_cells(:,1)))
                    % If it does, add the index to the selected_spot_indices array
                    selected_spot_indices(end+1) = i; %#ok
                end
            end

            % Store the selected spot indices in the app property
            %app.selected_spot = selected_spot_indices;

            % Validate newData
            if ischar(app.StringCompareInputEditField.Value) || isstring(app.StringCompareInputEditField.Value)
                if ~startsWith(lower(app.StringCompareInputEditField.Value), 'from')
                    % Try to convert to number
                    try
                        app.StringCompareInputEditField.Value = str2double(app.StringCompareInputEditField.Value);
                        if isnan(app.StringCompareInputEditField.Value)
                            app.StringCompareInputEditField.Valueata = [];
                        end
                    catch
                        app.StringCompareInputEditField.Value = [];
                    end
                end
            elseif ~isnumeric(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif isnan(app.StringCompareInputEditField.Value)
                app.StringCompareInputEditField.Value = [];
            elseif app.StringCompareInputEditField.Value < 0 
                app.StringCompareInputEditField.Value = [];
                % Check if it's the Fe ratio column and validate range for numeric inputs
            elseif strcmp(app.StringCompareDropDown.Value, 'FeO/(FeO + Fe2O3)') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 1)
                app.StringCompareInputEditField.Value = [];
            elseif strcmp(app.StringCompareDropDown.Value, '% Total Oxides') && (app.StringCompareInputEditField.Value < 0 || app.StringCompareInputEditField.Value > 100)
                app.StringCompareInputEditField.Value = [];
            end
            
            % Check if we have a valid mass fraction input
            if ~isempty(app.StringCompareInputEditField.Value) && ~isnan(app.StringCompareInputEditField.Value)

                newValues = repmat({app.StringCompareInputEditField.Value}, length(selected_spot_indices), 1);
                switch app.StringCompareDropDown.Value
                    case 'µg/g'
                        app.base.project.session.spot.internal_standard_mass_fraction{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;
                    case '% Total Oxides'
                        app.base.project.session.spot.internal_standard_oxide_wt{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;
                    case 'FeO/(FeO + Fe2O3)'
                        app.base.project.session.spot.Fe_ratio_for_total_oxide{app.BracketIdDropDown.Value, app.selected_PRM}(selected_spot_indices) = newValues;

                end

            end

            % Update the GUI
            %update_quantification_module_GUI(app);
        end

        % Value changed function: ShowDropDown
        function ShowDropDownValueChanged(app, event)
            update_results_module_GUI(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.BaseMenu = uimenu(app.UIFigure);
            app.BaseMenu.Text = 'Base';
            app.SavetoBaseandExitMenu = uimenu(app.BaseMenu);
            app.SavetoBaseandExitMenu.MenuSelectedFcn = createCallbackFcn(app, @SavetoBaseandExitMenuSelected, true);
            app.SavetoBaseandExitMenu.Text = 'Save to Base and Exit';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.ProgressPanel = uipanel(app.GridLayout);
            app.IntervalButton = uibutton(app.ProgressPanel, 'push');
            app.IntervalButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.IntervalButton.Text = 'Interval';
            app.ImportButton = uibutton(app.ProgressPanel, 'push');
            app.ImportButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ImportButton.Text = 'Import';
            app.ClassificationButton = uibutton(app.ProgressPanel, 'push');
            app.ClassificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.ClassificationButton.Text = 'Classification';
            app.DriftButton = uibutton(app.ProgressPanel, 'push');
            app.DriftButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.DriftButton.Text = 'Drift';
            app.QuantificationButton = uibutton(app.ProgressPanel, 'push');
            app.QuantificationButton.ButtonPushedFcn = createCallbackFcn(app, @QuantificationButtonPushed, true);
            app.QuantificationButton.Text = 'Quantification';
            app.ResultsButton = uibutton(app.ProgressPanel, 'push');
            app.ResultsButton.Text = 'Results';
            app.TabGroup = uitabgroup(app.GridLayout);
            app.RawResultsTab = uitab(app.TabGroup);
            app.RawResultsTab.Title = 'Raw Results';
            app.GridLayout2 = uigridlayout(app.RawResultsTab);
            app.ResultsTable = uitable(app.GridLayout2);
            app.ResultsTable.ColumnName = '';
            app.ResultsTable.RowName = {};
            app.HostInclusionDropDown = uidropdown(app.GridLayout2);
            app.HostInclusionDropDown.Items = {};
            app.HostInclusionDropDown.ValueChangedFcn = createCallbackFcn(app, @HostInclusionDropDownValueChanged, true);
            app.HostInclusionDropDown.Value = {};
            app.CreateOutputTableButton = uibutton(app.GridLayout2, 'push');
            app.CreateOutputTableButton.ButtonPushedFcn = createCallbackFcn(app, @CreateOutputTableButtonPushed, true);
            app.CreateOutputTableButton.Text = 'Create Output Table';
            app.PrimaryReferenceMaterialDropDownLabel = uilabel(app.GridLayout2);
            app.PrimaryReferenceMaterialDropDownLabel.Text = 'Primary Reference Material';
            app.PrimaryReferenceMaterialDropDown = uidropdown(app.GridLayout2);
            app.PrimaryReferenceMaterialDropDown.Items = {};
            app.PrimaryReferenceMaterialDropDown.ValueChangedFcn = createCallbackFcn(app, @PrimaryReferenceMaterialDropDownValueChanged, true);
            app.PrimaryReferenceMaterialDropDown.Value = {};
            app.BracketIdDropDownLabel = uilabel(app.GridLayout2);
            app.BracketIdDropDownLabel.Text = 'Bracket Id';
            app.BracketIdDropDown = uidropdown(app.GridLayout2);
            app.BracketIdDropDown.Items = {};
            app.BracketIdDropDown.ValueChangedFcn = createCallbackFcn(app, @BracketIdDropDownValueChanged, true);
            app.BracketIdDropDown.Value = {};
            app.ShowDropDownLabel = uilabel(app.GridLayout2);
            app.ShowDropDownLabel.Text = 'Show';
            app.ShowDropDown = uidropdown(app.GridLayout2);
            app.ShowDropDown.Items = {'Mass Fractions', 'Sensitivities', 'CPS', 'Limits of detection'};
            app.ShowDropDown.ValueChangedFcn = createCallbackFcn(app, @ShowDropDownValueChanged, true);
            app.ShowDropDown.Value = 'Mass Fractions';
            app.EndDataReductionButton = uibutton(app.GridLayout2, 'push');
            app.EndDataReductionButton.ButtonPushedFcn = createCallbackFcn(app, @EndDataReductionButtonPushed, true);
            app.EndDataReductionButton.Text = 'End Data Reduction';
            app.AddMetadataTab = uitab(app.TabGroup);
            app.AddMetadataTab.Title = 'Add Metadata';
            app.UITable = uitable(app.AddMetadataTab);
            app.UITable.ColumnName = '';
            app.UITable.RowName = {};
            app.Panel = uipanel(app.AddMetadataTab);
            app.ApplyButton = uibutton(app.Panel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.SpotnamecontainsEditFieldLabel = uilabel(app.Panel);
            app.SpotnamecontainsEditFieldLabel.Text = 'Spotname contains:';
            app.SpotnamecontainsEditField = uieditfield(app.Panel, 'text');
            app.StringCompareDropDown = uidropdown(app.Panel);
            app.StringCompareDropDown.Items = {''};
            app.StringCompareDropDown.Value = '';
            app.EditField = uieditfield(app.Panel, 'text');
            app.Panel_2 = uipanel(app.AddMetadataTab);
            app.AddButton = uibutton(app.Panel_2, 'push');
            app.AddButton.Text = 'Add';
            app.RemoveButton = uibutton(app.Panel_2, 'push');
            app.RemoveButton.Text = 'Remove';
            app.SampleCheckBox = uicheckbox(app.Panel_2);
            app.SampleCheckBox.Text = 'Sample';
            app.MineralCheckBox = uicheckbox(app.Panel_2);
            app.MineralCheckBox.Text = 'Mineral';
            app.LocationCheckBox = uicheckbox(app.Panel_2);
            app.LocationCheckBox.Text = 'Location';
            app.CommentCheckBox = uicheckbox(app.Panel_2);
            app.CommentCheckBox.Text = 'Comment';
            app.CustomEditFieldLabel = uilabel(app.Panel_2);
            app.CustomEditFieldLabel.Text = 'Custom ';
            app.CustomEditField = uieditfield(app.Panel_2, 'text');
            app.QualityControlTab = uitab(app.TabGroup);
            app.QualityControlTab.Title = 'Quality Control';
            app.CreateOutputTable = uitab(app.TabGroup);
            app.CreateOutputTable.Title = 'Create Output Table';
            app.ShowDropDown_2 = uidropdown(app.CreateOutputTable);
            app.ShowDropDown_2.Items = {'Mass Fractions', 'Sensitivities', 'CPS', 'Limits of detection'};
            app.ShowDropDown_2.Value = 'Mass Fractions';
            app.ShowDropDown_3 = uidropdown(app.CreateOutputTable);
            app.ShowDropDown_3.Items = {'All', 'Sort by PRM', 'Sort by bracket_id'};
            app.ShowDropDown_3.Value = 'All';
            app.WhichcolumnstoincludedefinedinmetadataandyieldLabel = uilabel(app.CreateOutputTable);
            app.WhichcolumnstoincludedefinedinmetadataandyieldLabel.Text = 'Which columns to include, defined in metadata and yield';
            app.SignificantdigitsLabel = uilabel(app.CreateOutputTable);
            app.SignificantdigitsLabel.Text = 'Significant digits';
            app.ColumnheaderswithoutMassesmassesextrarowLabel = uilabel(app.CreateOutputTable);
            app.ColumnheaderswithoutMassesmassesextrarowLabel.Text = 'Columnheaders without Masses, masses extra row';
            app.Averageand2sdpersamplemineraluswLabel = uilabel(app.CreateOutputTable);
            app.Averageand2sdpersamplemineraluswLabel.Text = 'Average and 2sd per sample, mineral usw';
            app.ShowDropDown_4 = uidropdown(app.CreateOutputTable);
            app.ShowDropDown_4.Items = {'Host', 'Inclusion'};
            app.ShowDropDown_4.Value = 'Host';
            app.SignalandBackgroundLengthLabel = uilabel(app.CreateOutputTable);
            app.SignalandBackgroundLengthLabel.Text = 'Signal and Background Length';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Results_Module(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end